classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CoordinatesEditField       matlab.ui.control.EditField
        CoordinatesEditFieldLabel  matlab.ui.control.Label
        DetectButton               matlab.ui.control.Button
        RaspberryPiPanel           matlab.ui.container.Panel
        CaptureButton              matlab.ui.control.Button
        ConfigureButton            matlab.ui.control.Button
        PresavedImagePanel         matlab.ui.container.Panel
        LoadButton                 matlab.ui.control.Button
        KiwiFruitDetectionLabel    matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Detector % Description
        RaspiObj
        CamObj
        Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             
         addpath(genpath('train'));
            addpath(genpath('train2'));
            app.Detector = load('detector.mat');
            app.CaptureButton.Enable = 'off';

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)

            app.Image = imread(uigetfile('*.*'));
            imshow(app.Image,'Parent',app.UIAxes);
            title('Input Image','Parent',app.UIAxes);
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            [bboxes,scores,~] = detect(app.Detector.detector,app.Image);
            % Display the results.
            app.Image = insertObjectAnnotation(app.Image, "rectangle", bboxes, scores);
            imshow(app.Image, 'Parent', app.UIAxes);
            title('Detected Kiwi Fruits', 'Parent', app.UIAxes);

            if ~isempty(bboxes)
                app.CoordinatesEditField.Value = num2str(boundingBoxToCentroid(bboxes));
            end
        end

        % Button pushed function: ConfigureButton
        function ConfigureButtonPushed(app, event)
            app.RaspiObj = raspi();
            app.CamObj = cameraboard(app.RaspiObj);
            app.ConfigureButton.Enable = 'off';
            app.CaptureButton.Enable = 'on';
        end

        % Button pushed function: CaptureButton
        function CaptureButtonPushed(app, event)
             app.Image = snapshot(app.CamObj);
            imshow(app.Image,'Parent',app.UIAxes);
            title('Input Image','Parent',app.UIAxes);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.KiwiFruitDetectionLabel = uilabel(app.UIFigure);
            app.KiwiFruitDetectionLabel.Text = 'Kiwi Fruit Detection';
            app.PresavedImagePanel = uipanel(app.UIFigure);
            app.PresavedImagePanel.Title = 'Presaved Image';
            app.LoadButton = uibutton(app.PresavedImagePanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.RaspberryPiPanel = uipanel(app.UIFigure);
            app.RaspberryPiPanel.Title = 'Raspberry Pi';
            app.ConfigureButton = uibutton(app.RaspberryPiPanel, 'push');
            app.ConfigureButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureButtonPushed, true);
            app.ConfigureButton.Text = 'Configure';
            app.CaptureButton = uibutton(app.RaspberryPiPanel, 'push');
            app.CaptureButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureButtonPushed, true);
            app.CaptureButton.Text = 'Capture';
            app.DetectButton = uibutton(app.UIFigure, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = 'Detect';
            app.CoordinatesEditFieldLabel = uilabel(app.UIFigure);
            app.CoordinatesEditFieldLabel.Text = 'Coordinates';
            app.CoordinatesEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end